classdef filtros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FILTROSPanel                    matlab.ui.container.Panel
        RuidoButton                     matlab.ui.control.Button
        FiltroButtonGroup               matlab.ui.container.ButtonGroup
        MedianaButton                   matlab.ui.control.RadioButton
        ModaButton                      matlab.ui.control.RadioButton
        MaxyminButton                   matlab.ui.control.RadioButton
        RuidoButtonGroup                matlab.ui.container.ButtonGroup
        SaltandpepperButton             matlab.ui.control.ToggleButton
        GaussianoButton                 matlab.ui.control.Button
        ORIGINALLabel                   matlab.ui.control.Label
        RUIDOLabel                      matlab.ui.control.Label
        RESULTADOLabel                  matlab.ui.control.Label
        RuidoImage                      matlab.ui.control.Image
        OriginalImage                   matlab.ui.control.Image
        ImagenesdisponiblesButtonGroup  matlab.ui.container.ButtonGroup
        PeppersButton                   matlab.ui.control.ToggleButton
        FootballButton                  matlab.ui.control.ToggleButton
        MemeButton                      matlab.ui.control.ToggleButton
        ImagenButton                    matlab.ui.control.Button
        ResultadoImage                  matlab.ui.control.Image
        FiltradoButton                  matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        img_original
        img_ruido
        img_resultado 
    end
    
    methods (Access = private)
        
        
        function img_resultado = filtroMediana(app)
            [filas,columnas,niveles]=size(app.img_ruido);
            img_resultado=zeros(filas,columnas,niveles,"uint8");
            img_roja = app.img_ruido(:,:,1);
            img_verde = app.img_ruido(:,:,2);
            img_azul = app.img_ruido(:,:,3);
            
            img_roja_f=iteracionPorDimensionFM(app,img_roja);
            img_verde_f=iteracionPorDimensionFM(app,img_verde);
            img_azul_f=iteracionPorDimensionFM(app,img_azul);

            img_resultado(:,:,1)=img_roja_f;
            img_resultado(:,:,2)=img_verde_f;
            img_resultado(:,:,3)=img_azul_f;
           
        end
         
        function imagenFiltrada = iteracionPorDimensionFM(app,imagen)
            [filas,columnas]=size(imagen);
            imagenFiltrada = zeros(filas,columnas,'uint8');
            for fila=1:filas
                for columna=1:columnas
                    mascara = crearMascara(app,imagen,fila,columna);
                    nuevoValor= ordenarMascara(app,mascara);
                    imagenFiltrada(fila,columna)=nuevoValor;
                end
            end
        end
        function mascara = crearMascara(app,imagen,fila,columna)
            mascara = zeros(1,9);
            mascara(1)=verificar_indice(app,imagen,fila-1,columna-1);
            mascara(2)=verificar_indice(app,imagen,fila-1,columna);
            mascara(3)=verificar_indice(app,imagen,fila-1,columna+1);
            mascara(4)=verificar_indice(app,imagen,fila,columna-1);
            mascara(5)=verificar_indice(app,imagen,fila,columna);
            mascara(6)=verificar_indice(app,imagen,fila,columna+1);
            mascara(7)=verificar_indice(app,imagen,fila+1,columna-1);
            mascara(8)=verificar_indice(app,imagen,fila+1,columna);
            mascara(9)=verificar_indice(app,imagen,fila+1,columna+1);
        end
        
        function valor = verificar_indice(app,imagen,fila,columna)
            try
                valor = imagen(fila, columna);
            catch
                valor = 0;
            end
        end
       
        
        function nuevoValor = ordenarMascara(app,mascara)
            mascaraOrdenada=mascara;
            [d,n]=size(mascaraOrdenada);
            for i=2:n
               for j=i:-1:2 
                    if mascaraOrdenada(j)<mascaraOrdenada(j-1)
                        temp=mascaraOrdenada(j-1);
                        mascaraOrdenada(j-1)=mascaraOrdenada(j);
                        mascaraOrdenada(j)=temp;
                    end
               end
            end
            nuevoValor=uint8(mascaraOrdenada(5));
            
            %mascaraOrdenada = sort(mascara);
            
            %nuevoValor=uint8(mascaraOrdenada(5));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RuidoButton
        function RuidoButtonPushed(app, event)
            % Ruido salt and pepper
            if app.SaltandpepperButton.Value
                app.img_ruido=imnoise(app.img_original,'salt & pepper',0.4);
                app.RuidoImage.ImageSource=app.img_ruido; 
                app.FiltradoButton.Enable='on';
                app.RuidoButton.Enable='off';
            end
        end

        % Button pushed function: ImagenButton
        function ImagenButtonPushed(app, event)
            if app.PeppersButton.Value
                app.img_original= imread('peppers.png');
            elseif app.FootballButton.Value
                app.img_original= imread('football.jpg');
            elseif app.MemeButton.Value
                app.img_original = imread('meme.jpg');
            end
            app.OriginalImage.ImageSource=app.img_original;                             
            app.RuidoButton.Enable='on';
            app.ImagenButton.Enable='off';
        end

        % Button pushed function: FiltradoButton
        function FiltradoButtonPushed(app, event)
            %filtroMediana(app);
            app.img_resultado=filtroMediana(app);
            app.ResultadoImage.ImageSource=app.img_resultado;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            null_img=zeros(300,300,3);
            app.OriginalImage.ImageSource=null_img; 
            app.RuidoImage.ImageSource=null_img;
            app.ResultadoImage.ImageSource=null_img;
            app.ImagenButton.Enable='on';
            app.RuidoButton.Enable='off';
            app.FiltradoButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILTROSPanel = uipanel(app.UIFigure);
            app.FILTROSPanel.Title = 'FILTROS';
            app.RuidoButton = uibutton(app.FILTROSPanel, 'push');
            app.RuidoButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoButtonPushed, true);
            app.RuidoButton.Enable = 'off';
            app.RuidoButton.Text = 'Ruido';
            app.FiltroButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.FiltroButtonGroup.Title = 'Filtro';
            app.MedianaButton = uiradiobutton(app.FiltroButtonGroup);
            app.MedianaButton.Text = 'Mediana';
            app.MedianaButton.Value = true;
            app.ModaButton = uiradiobutton(app.FiltroButtonGroup);
            app.ModaButton.Text = 'Moda';
            app.MaxyminButton = uiradiobutton(app.FiltroButtonGroup);
            app.MaxyminButton.Text = 'Max y min';
            app.RuidoButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.RuidoButtonGroup.Title = 'Ruido';
            app.SaltandpepperButton = uitogglebutton(app.RuidoButtonGroup);
            app.SaltandpepperButton.Text = 'Salt and pepper';
            app.SaltandpepperButton.Value = true;
            app.GaussianoButton = uibutton(app.RuidoButtonGroup, 'push');
            app.GaussianoButton.Text = 'Gaussiano';
            app.ORIGINALLabel = uilabel(app.FILTROSPanel);
            app.ORIGINALLabel.Text = 'ORIGINAL';
            app.RUIDOLabel = uilabel(app.FILTROSPanel);
            app.RUIDOLabel.Text = 'RUIDO';
            app.RESULTADOLabel = uilabel(app.FILTROSPanel);
            app.RESULTADOLabel.Text = 'RESULTADO';
            app.RuidoImage = uiimage(app.FILTROSPanel);
            app.OriginalImage = uiimage(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup.Title = 'Imagenes disponibles';
            app.PeppersButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.PeppersButton.Text = 'Peppers';
            app.PeppersButton.Value = true;
            app.FootballButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.FootballButton.Text = 'Football';
            app.MemeButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.MemeButton.Text = 'Meme';
            app.ImagenButton = uibutton(app.FILTROSPanel, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenButtonPushed, true);
            app.ImagenButton.Text = 'Imagen';
            app.ResultadoImage = uiimage(app.FILTROSPanel);
            app.FiltradoButton = uibutton(app.FILTROSPanel, 'push');
            app.FiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @FiltradoButtonPushed, true);
            app.FiltradoButton.Enable = 'off';
            app.FiltradoButton.Text = 'Filtrado';
            app.RESETButton = uibutton(app.FILTROSPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end