classdef memorama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        JUGARButton            matlab.ui.control.Button
        MEMORAMALabel          matlab.ui.control.Label
        JugadorEditField       matlab.ui.control.EditField
        JugadorEditFieldLabel  matlab.ui.control.Label
        AgregarButton          matlab.ui.control.Button
        TurnoLabel             matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        UITable2               matlab.ui.control.Table
        RightPanel             matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        Image5                 matlab.ui.control.Image
        Image6                 matlab.ui.control.Image
        Image7                 matlab.ui.control.Image
        Image8                 matlab.ui.control.Image
        Image9                 matlab.ui.control.Image
        Image10                matlab.ui.control.Image
        Image11                matlab.ui.control.Image
        Image12                matlab.ui.control.Image
        Image13                matlab.ui.control.Image
        Image14                matlab.ui.control.Image
        Image15                matlab.ui.control.Image
        Image16                matlab.ui.control.Image
        Image17                matlab.ui.control.Image
        Image18                matlab.ui.control.Image
        Image19                matlab.ui.control.Image
        Image20                matlab.ui.control.Image
        Image21                matlab.ui.control.Image
        Image22                matlab.ui.control.Image
        Image23                matlab.ui.control.Image
        Image24                matlab.ui.control.Image
        Image25                matlab.ui.control.Image
        Image26                matlab.ui.control.Image
        Image27                matlab.ui.control.Image
        Image28                matlab.ui.control.Image
        Image29                matlab.ui.control.Image
        Image30                matlab.ui.control.Image
        Image31                matlab.ui.control.Image
        Image32                matlab.ui.control.Image
        Image33                matlab.ui.control.Image
        Image34                matlab.ui.control.Image
        Image35                matlab.ui.control.Image
        Image36                matlab.ui.control.Image
        Image37                matlab.ui.control.Image
        Image38                matlab.ui.control.Image
        Image39                matlab.ui.control.Image
        Image40                matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        turno = 1;
        jugadores = {} % Description
        jugador_actual = '' % Description
        bandera_cambio_turno = 0;
    end
    
    methods (Access = public)
        function set_turno(app)
            jugador_actual = app.jugadores(app.turno);
            app.Label.Text = jugador_actual;
            app.jugador_actual = jugador_actual
            disp('Turno de ' + app.jugador_actual)
            if app.bandera_cambio_turno < 2
                app.bandera_cambio_turno = app.bandera_cambio_turno + 1;
            else
                app.bandera_cambio_turno = 1;
                if(app.turno < size(app.jugadores))
                    app.turno = app.turno + 1;
                else
                    app.turno = 1;
                end 
            end
        end
        
        function actualiza_tabla_imagen(app)
            
        end
        
        function habilitar_imagenes(app)
            set(app.Image, 'Enable', 'on')
            set(app.Image2, 'Enable', 'on')
            set(app.Image3, 'Enable', 'on')
            set(app.Image4, 'Enable', 'on')
            set(app.Image5, 'Enable', 'on')
            set(app.Image6, 'Enable', 'on')
            set(app.Image7, 'Enable', 'on')
            set(app.Image8, 'Enable', 'on')
            set(app.Image9, 'Enable', 'on')
            set(app.Image10, 'Enable', 'on')
            set(app.Image11, 'Enable', 'on')
            set(app.Image12, 'Enable', 'on')
            set(app.Image13, 'Enable', 'on')
            set(app.Image14, 'Enable', 'on')
            set(app.Image15, 'Enable', 'on')
            set(app.Image16, 'Enable', 'on')
            set(app.Image17, 'Enable', 'on')
            set(app.Image18, 'Enable', 'on')
            set(app.Image19, 'Enable', 'on')
            set(app.Image21, 'Enable', 'on')
            set(app.Image22, 'Enable', 'on')
            set(app.Image23, 'Enable', 'on')
            set(app.Image24, 'Enable', 'on')
            set(app.Image25, 'Enable', 'on')
            set(app.Image26, 'Enable', 'on')
            set(app.Image27, 'Enable', 'on')
            set(app.Image28, 'Enable', 'on')
            set(app.Image29, 'Enable', 'on')
            set(app.Image30, 'Enable', 'on')
            set(app.Image31, 'Enable', 'on')
            set(app.Image32, 'Enable', 'on')
            set(app.Image33, 'Enable', 'on')
            set(app.Image34, 'Enable', 'on')
            set(app.Image35, 'Enable', 'on')
            set(app.Image36, 'Enable', 'on')
            set(app.Image37, 'Enable', 'on')
            set(app.Image38, 'Enable', 'on')
            set(app.Image39, 'Enable', 'on')
            set(app.Image40, 'Enable', 'on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JUGARButton
        function JUGARButtonPushed(app, event)
                      
            if(size(app.jugadores))
                set(app.AgregarButton, 'Enable', 'off')
                set(app.JUGARButton, 'Enable', 'off')
                app.JugadorEditField.Value = '';
                set(app.JugadorEditField, 'Enable', 'off')
                
                habilitar_imagenes(app)
              
                set_turno(app)
                
            else
                disp('Agrega un jugador antes de iniciar!')
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            % Agreagr jugador a la tabla de puntuación
            if(app.JugadorEditField.Value)
                new_row = [string(app.JugadorEditField.Value), 0];
                old_data = get(app.UITable, 'Data');
                new_data = [old_data; new_row];
                set(app.UITable, 'Data', new_data)
                disp(new_data)
                app.JugadorEditField.Value = '';
                app.jugadores = get(app.UITable, 'Data');
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            disp('Click on imagen 1')
            set_turno(app)
            actualiza_tabla_imagen(app)
            % set(app.Image, 'Enable', 'off')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {749, 749};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Jugador'; 'Puntuación'};
            app.UITable.RowName = {};
            app.JUGARButton = uibutton(app.GridLayout3, 'push');
            app.JUGARButton.ButtonPushedFcn = createCallbackFcn(app, @JUGARButtonPushed, true);
            app.JUGARButton.Text = 'JUGAR';
            app.MEMORAMALabel = uilabel(app.GridLayout3);
            app.MEMORAMALabel.Text = 'MEMORAMA';
            app.JugadorEditField = uieditfield(app.GridLayout3, 'text');
            app.JugadorEditFieldLabel = uilabel(app.GridLayout3);
            app.JugadorEditFieldLabel.Text = 'Jugador';
            app.AgregarButton = uibutton(app.GridLayout3, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.TurnoLabel = uilabel(app.GridLayout3);
            app.TurnoLabel.Text = 'Turno:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'Imagen 1'; 'Imagen 2'};
            app.UITable2.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'pexels-aliko-sunawang-5288737.jpg';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'pexels-dimitry-zub-6597554.jpg';
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.Enable = 'off';
            app.Image3.ImageSource = 'pexels-daria-shevtsova-8285228.jpg';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.Enable = 'off';
            app.Image4.ImageSource = 'pexels-daria-shevtsova-8285483.jpg';
            app.Image5 = uiimage(app.GridLayout2);
            app.Image5.Enable = 'off';
            app.Image5.ImageSource = 'pexels-david-selbert-6468065.jpg';
            app.Image6 = uiimage(app.GridLayout2);
            app.Image6.Enable = 'off';
            app.Image6.ImageSource = 'pexels-david-selbert-7853219.jpg';
            app.Image7 = uiimage(app.GridLayout2);
            app.Image7.Enable = 'off';
            app.Image7.ImageSource = 'pexels-david-selbert-7853223.jpg';
            app.Image8 = uiimage(app.GridLayout2);
            app.Image8.Enable = 'off';
            app.Image8.ImageSource = 'pexels-arthouse-studio-4338256.jpg';
            app.Image9 = uiimage(app.GridLayout2);
            app.Image9.Enable = 'off';
            app.Image9.ImageSource = 'pexels-maria-orlova-4946515.jpg';
            app.Image10 = uiimage(app.GridLayout2);
            app.Image10.Enable = 'off';
            app.Image10.ImageSource = 'pexels-lelani-badenhorst-6790680.jpg';
            app.Image11 = uiimage(app.GridLayout2);
            app.Image11.Enable = 'off';
            app.Image11.ImageSource = 'pexels-dimitry-zub-6743880.jpg';
            app.Image12 = uiimage(app.GridLayout2);
            app.Image12.Enable = 'off';
            app.Image12.ImageSource = 'pexels-julia-volk-5273640.jpg';
            app.Image13 = uiimage(app.GridLayout2);
            app.Image13.Enable = 'off';
            app.Image13.ImageSource = 'pexels-elina-sazonova-4240093.jpg';
            app.Image14 = uiimage(app.GridLayout2);
            app.Image14.Enable = 'off';
            app.Image14.ImageSource = 'pexels-enric-cruz-lópez-6039178.jpg';
            app.Image15 = uiimage(app.GridLayout2);
            app.Image15.Enable = 'off';
            app.Image15.ImageSource = 'pexels-mathias-pr-reding-6966038.jpg';
            app.Image16 = uiimage(app.GridLayout2);
            app.Image16.Enable = 'off';
            app.Image16.ImageSource = 'pexels-wencheng-jiang-6832295.jpg';
            app.Image17 = uiimage(app.GridLayout2);
            app.Image17.Enable = 'off';
            app.Image17.ImageSource = 'pexels-sasha-maslova-8388520.jpg';
            app.Image18 = uiimage(app.GridLayout2);
            app.Image18.Enable = 'off';
            app.Image18.ImageSource = 'pexels-nicolle-kreisch-6888608.jpg';
            app.Image19 = uiimage(app.GridLayout2);
            app.Image19.Enable = 'off';
            app.Image19.ImageSource = 'pexels-valeriya-kobzar-8155561.jpg';
            app.Image20 = uiimage(app.GridLayout2);
            app.Image20.Enable = 'off';
            app.Image20.ImageSource = 'pexels-rachel-claire-4846516.jpg';
            app.Image21 = uiimage(app.GridLayout2);
            app.Image21.Enable = 'off';
            app.Image21.ImageSource = 'pexels-dimitry-zub-6597554.jpg';
            app.Image22 = uiimage(app.GridLayout2);
            app.Image22.Enable = 'off';
            app.Image22.ImageSource = 'pexels-david-selbert-7853219.jpg';
            app.Image23 = uiimage(app.GridLayout2);
            app.Image23.Enable = 'off';
            app.Image23.ImageSource = 'pexels-arthouse-studio-4338256.jpg';
            app.Image24 = uiimage(app.GridLayout2);
            app.Image24.Enable = 'off';
            app.Image24.ImageSource = 'pexels-wencheng-jiang-6832295.jpg';
            app.Image25 = uiimage(app.GridLayout2);
            app.Image25.Enable = 'off';
            app.Image25.ImageSource = 'pexels-david-selbert-6468065.jpg';
            app.Image26 = uiimage(app.GridLayout2);
            app.Image26.Enable = 'off';
            app.Image26.ImageSource = 'pexels-dimitry-zub-6743880.jpg';
            app.Image27 = uiimage(app.GridLayout2);
            app.Image27.Enable = 'off';
            app.Image27.ImageSource = 'pexels-mathias-pr-reding-6966038.jpg';
            app.Image28 = uiimage(app.GridLayout2);
            app.Image28.Enable = 'off';
            app.Image28.ImageSource = 'pexels-valeriya-kobzar-8155561.jpg';
            app.Image29 = uiimage(app.GridLayout2);
            app.Image29.Enable = 'off';
            app.Image29.ImageSource = 'pexels-julia-volk-5273640.jpg';
            app.Image30 = uiimage(app.GridLayout2);
            app.Image30.Enable = 'off';
            app.Image30.ImageSource = 'pexels-aliko-sunawang-5288737.jpg';
            app.Image31 = uiimage(app.GridLayout2);
            app.Image31.Enable = 'off';
            app.Image31.ImageSource = 'pexels-sasha-maslova-8388520.jpg';
            app.Image32 = uiimage(app.GridLayout2);
            app.Image32.Enable = 'off';
            app.Image32.ImageSource = 'pexels-enric-cruz-lópez-6039178.jpg';
            app.Image33 = uiimage(app.GridLayout2);
            app.Image33.Enable = 'off';
            app.Image33.ImageSource = 'pexels-lelani-badenhorst-6790680.jpg';
            app.Image34 = uiimage(app.GridLayout2);
            app.Image34.Enable = 'off';
            app.Image34.ImageSource = 'pexels-david-selbert-7853223.jpg';
            app.Image35 = uiimage(app.GridLayout2);
            app.Image35.Enable = 'off';
            app.Image35.ImageSource = 'pexels-daria-shevtsova-8285228.jpg';
            app.Image36 = uiimage(app.GridLayout2);
            app.Image36.Enable = 'off';
            app.Image36.ImageSource = 'pexels-rachel-claire-4846516.jpg';
            app.Image37 = uiimage(app.GridLayout2);
            app.Image37.Enable = 'off';
            app.Image37.ImageSource = 'pexels-nicolle-kreisch-6888608.jpg';
            app.Image38 = uiimage(app.GridLayout2);
            app.Image38.Enable = 'off';
            app.Image38.ImageSource = 'pexels-elina-sazonova-4240093.jpg';
            app.Image39 = uiimage(app.GridLayout2);
            app.Image39.Enable = 'off';
            app.Image39.ImageSource = 'pexels-maria-orlova-4946515.jpg';
            app.Image40 = uiimage(app.GridLayout2);
            app.Image40.Enable = 'off';
            app.Image40.ImageSource = 'pexels-daria-shevtsova-8285483.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memorama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end